classdef mytfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        ProyectoTorricelliyMariotteLabel  matlab.ui.control.Label
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
        MariotteButton    matlab.ui.control.Button
        TorricelliButton  matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MariotteButton
        function MariotteButtonPushed(app, event)
           clear all; close all; clc;
%--------------
%botella
posBx=0;
posBy=0;

Bbase = .08;
Baltura = .174;
botellaX = [posBx, posBx, posBx+Bbase, posBx+Bbase];
botellaY = [posBy+Baltura, posBy, posBy, posBy+Baltura];

grafica = plot(botellaX, botellaY);
set(grafica, "linewidth",3,"color","black");
axis([-.01,.06,-.01,.11])
axis equal;


%---------------
%valvula
diametroVal = .006;
longVal = .01;
posValH = .03;
areaVal = pi * (diametroVal/2)^2;

valvula = rectangle ("Position", [posBx + Bbase, posBy + posValH,longVal, diametroVal]);


%agua
aguaH(1)=.13;
baseArea = pi*(Bbase/2)^2;
aguaVol = baseArea*aguaH(1);
agua = rectangle("position",[posBx,posBy,Bbase,aguaH(1)]);
set(agua,"facecolor","blue")
%------------------------------------------

ti=0; t(1)=0;
tf=45; % tiempo para poder igualar con tracker
N=100;
dt=(tf-ti)/N;

% posición texto dinamico
textPos = [0.55 0.8];

% popte
x = 0.04;      % coordenada x
y = 0.04;    % coordenada y
w = 0.004;               % ancho
h = 0.12;               % altura rectangulo
rect = rectangle('Position',[x,y,w,h],'FaceColor','black'); % crear rectangulo
rotate(rect,[0 0 1],90); 


%La altura aguah no se no se modifica en el for
for n=1:N
    delete(agua)
    t(n+1)=ti+n*dt;


if aguaH(n) > y % condición para cuando el agua este cubriendo al popote
    v_toricelli = sqrt(2*9.81*(y-posValH-diametroVal/2));
else
     v_toricelli=sqrt(2*9.81*(aguaH(n)-posValH-diametroVal/2));


end 
    if aguaH(n) > posValH+diametroVal
        G = v_toricelli*dt*areaVal;

    else 
        G = 0;
    end

 
    aguaVol=aguaVol-G;
    aguaH(n+1)=aguaVol/baseArea;
    agua = rectangle("position",[posBx,posBy,Bbase,aguaH(n+1)]);
    set(agua,"facecolor","blue")
    pause(0.01)
    
    
    texto = sprintf('t = %.2f s\nv_t = %.2f cm/s', t(n+1), v_toricelli);
    annotation('textbox', [textPos 0.1 0.1], 'String', texto, 'FitBoxToText', 'on', 'FontSize', 14, 'BackgroundColor', 'white')
    
    drawnow 
end

% mariotte


%Grafica de altura-agua respecto a tiempo
figure(2)
matf = plot(t,aguaH);
set(matf) 
hold on 
%Mariotte 1
A =  -2.008e-3;
B = 1.046e-1;

%Mariotte 2
A1 = -2.022e-3;
B1 = 1.344e-1;


y = A*t + B;
y2 = A1 * t + B1;

plot(t,y,t,y2)

legend('Matlab', 'Tracker 1', 'Tracker 2');


%-----------------------------
% histograma

% Array de tiempos
t = [104.7 103 102 101.5 101.5 ];

% mean y sigma
m = mean(t);
sigma = std(t);

% x axis distribución normal
x = linspace(min(t), max(t), 1000);

% y-axis distribución normal
y = normpdf(x, m, sigma);

% histograma
figure(3)
histogram(t, 'Normalization', 'pdf', 'BinWidth', 1);
hold on;

% distribución normal
figure(3)
plot(x, y, 'LineWidth', 2);
hold on

% leyendas y titulo
title('Histograma de tiempos vs distribución normal');
xlabel('tiempos');
ylabel('Densidad de probabilidad');
legend('Histograma', 'Distribución normal');


        end

        % Button pushed function: TorricelliButton
        function TorricelliButtonPushed(app, event)
      
clear all; close all; clc;
%--------------
%botella
posBx=0;
posBy=0;

Bbase = .08;
Baltura = .174;
botellaX = [posBx, posBx, posBx+Bbase, posBx+Bbase];
botellaY = [posBy+Baltura, posBy, posBy, posBy+Baltura];

grafica = plot(botellaX, botellaY);
set(grafica, "linewidth",3,"color","black");
axis([-.01,.06,-.01,.11])
axis equal;

%---------------
%valvula
diametroVal = .0055;
longVal = .01;
posValH = .03;
areaVal = pi * (diametroVal/2)^2;

valvula = rectangle ("Position", [posBx + Bbase, posBy + posValH,longVal, diametroVal])

%agua
aguaH(1)=.13;
baseArea = pi*(Bbase/2)^2;
aguaVol = baseArea*aguaH(1);
agua = rectangle("position",[posBx,posBy,Bbase,aguaH(1)]);
set(agua,"facecolor","blue")
%-------------------

ti=0; t(1)=0;
tf=24;
N=100;
dt=(tf-ti)/N;

% posición texto dinamico
textPos = [0.55 0.8];

% Iteración para agua
for n=1:N
    delete(agua)
    t(n+1)=ti+n*dt;
    v_toricelli=sqrt(2*9.81*(aguaH(n)-posValH-diametroVal/2));
    
    if aguaH(n) > posValH+diametroVal
        G = v_toricelli*dt*areaVal;

    else 
        G = 0;
    end


    aguaVol=aguaVol-G;
    aguaH(n+1)=aguaVol/baseArea;
    agua = rectangle("position",[posBx,posBy,Bbase,aguaH(n+1)]);
    set(agua,"facecolor","blue")
    pause(0.01)
    
    % letrero dinámico
    texto = sprintf('t = %.2f s\nv_t = %.2f cm/s', t(n+1), v_toricelli);
    annotation('textbox', [textPos 0.1 0.1], 'String', texto, 'FitBoxToText', 'on', 'FontSize', 14, 'BackgroundColor', 'white')
    
    drawnow 
end

%Grafica de altura-agua respecto a tiempo
figure(2)
matf = plot(t,aguaH);
set(matf) 
grid on
hold on 


% Torricelli 1
A =  1.496e-4;
B = -8.53e-3;
C = 1.537e-1;

%Torricelli 2
A1 = 1.611e-4;
B1 = -8.39e-3;
C1 = 1.449e-1;
%Torricelli 3

A2 = 1.34e-4;
B2 = -8.039e-3;
C2 =1.46e-1;


%plot de trackers vs matlab
y = A*t.^2 + B * t + C;
y2 = A1 * t.^2 +B1 * t +C1;
y3 = A2 * t.^2 +B2 * t +C2; 
plot(t,y,t,y2,t,y3)
legend('Matlab', 'Tracker 1', 'Tracker 2', 'Tracker 3');
xlabel('Tiempo (s)', 'FontSize', 14)
ylabel('Altura del agua (m)', 'FontSize', 14)
title('Altura del agua en la botella vs. tiempo', 'FontSize', 16)


%__________________________________________________________
% Array de tiempos
t = [30 30.4 30.2 30.3 30.1 30.2 30.3 30.0 30.4 30.1 30.2]; %Categorías de tiempos

% mean y sigma
m = mean(t);
sigma = std(t);

% x axis distribución normal
x = linspace(min(t), max(t), 1000);

% y-axis distribución normal
y = normpdf(x, m, sigma);

% histograma
figure(3)
histogram(t, 'Normalization', 'pdf', 'BinWidth', 0.1);
hold on;

% distribución normal
figure(3)
plot(x, y, 'LineWidth', 2);
hold on

% leyendas y titulo
title('Histograma de tiempos vs distribución normal');
xlabel('tiempos');
ylabel('Densidad de probabilidad');
legend('Histograma', 'Distribución normal');


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {514, 514};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {494, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TorricelliButton = uibutton(app.LeftPanel, 'push');
            app.TorricelliButton.ButtonPushedFcn = createCallbackFcn(app, @TorricelliButtonPushed, true);
            app.TorricelliButton.Text = 'Torricelli';
            app.MariotteButton = uibutton(app.LeftPanel, 'push');
            app.MariotteButton.ButtonPushedFcn = createCallbackFcn(app, @MariotteButtonPushed, true);
            app.MariotteButton.Text = 'Mariotte';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2023-03-13 a la(s) 10.38.44.png');
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2023-03-13 a la(s) 10.39.29.png');
            app.ProyectoTorricelliyMariotteLabel = uilabel(app.LeftPanel);
            app.ProyectoTorricelliyMariotteLabel.Text = 'Proyecto Torricelli y Mariotte';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mytfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end